classdef audio_samp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlayButton                     matlab.ui.control.Button
        ByZhuoxuanShiJustinCanasNghiaNguyenLabel  matlab.ui.control.Label
        ENG6AudioSamplerLabel          matlab.ui.control.Label
        AdjustSampleRateButton         matlab.ui.control.Button
        SampleRateadjHzEditField       matlab.ui.control.NumericEditField
        SampleRateadjHzEditFieldLabel  matlab.ui.control.Label
        CropAudioButton                matlab.ui.control.Button
        EndsEditField                  matlab.ui.control.NumericEditField
        EndsEditFieldLabel             matlab.ui.control.Label
        BeginsEditField                matlab.ui.control.NumericEditField
        BeginsEditFieldLabel           matlab.ui.control.Label
        SquareButton                   matlab.ui.control.Button
        SawtoothButton                 matlab.ui.control.Button
        SineButton                     matlab.ui.control.Button
        SynthButton                    matlab.ui.control.Button
        DFactorKnob                    matlab.ui.control.Knob
        DFactorKnobLabel               matlab.ui.control.Label
        PFactorKnob                    matlab.ui.control.Knob
        PFactorKnobLabel               matlab.ui.control.Label
        TempoKnob                      matlab.ui.control.Knob
        TempoKnobLabel                 matlab.ui.control.Label
        VolumeKnob                     matlab.ui.control.Knob
        VolumeKnobLabel                matlab.ui.control.Label
        EQLabel                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        HzSlider_5                     matlab.ui.control.Slider
        HzSlider_4                     matlab.ui.control.Slider
        HzSlider_3                     matlab.ui.control.Slider
        HzSlider_2                     matlab.ui.control.Slider
        HzSlider                       matlab.ui.control.Slider
        AmpRawGauge                    matlab.ui.control.LinearGauge
        AmpRawGaugeLabel               matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
        L9Button                       matlab.ui.control.Button
        L8Button                       matlab.ui.control.Button
        L7Button                       matlab.ui.control.Button
        L6Button                       matlab.ui.control.Button
        L5Button                       matlab.ui.control.Button
        L4Button                       matlab.ui.control.Button
        L3Button                       matlab.ui.control.Button
        L2Button                       matlab.ui.control.Button
        L1Button                       matlab.ui.control.Button
        waveraw                        matlab.ui.control.UIAxes
        waveproc                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CropAudioButton
        function CropAudioButtonPushed(app, event)

            initialValue = str2double(app.BeginsEditField.Value);
            endValue = str2double(app.EndsEditField.Value);    

            initialSample = round(initialValue * app.MemAudioFS);
            endSample = round(endValue * app.MemAudioFS);

            app.MeMaudioArray = app.MeMaudioArray(initialSample:endSample, :);

            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
        end

        % Button pushed function: SineButton
        function SineButtonPushed(app, event)
            t = (1 : length(app.MeMaudioArray)) / app.MemAudioFS;
            plot(t,sin(app.MeMaudioArray))
        end

        % Button pushed function: SawtoothButton
        function SawtoothButtonPushed(app, event)
            T = 1000; 
            fs = app.MemAudioFS;
            t = 0:1/fs:T-1/fs;
            x = sawtooth(2*pi*50*t);
        end

        % Button pushed function: SquareButton
        function SquareButtonPushed(app, event)
            x = app.MemAudioFS * app.MeMaudioArray;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.waveproc = uiaxes(app.UIFigure);
            title(app.waveproc, 'Wave Sample (Processed)')
            xlabel(app.waveproc, 'Samples (t)')
            ylabel(app.waveproc, 'Amp')
            zlabel(app.waveproc, 'Z')
            app.waveraw = uiaxes(app.UIFigure);
            title(app.waveraw, 'Wave Sample (Raw)')
            xlabel(app.waveraw, 'Samples (t)')
            ylabel(app.waveraw, 'Amp')
            zlabel(app.waveraw, 'Z')
            app.L1Button = uibutton(app.UIFigure, 'push');
            app.L1Button.Text = 'L1';
            app.L2Button = uibutton(app.UIFigure, 'push');
            app.L2Button.Text = 'L2';
            app.L3Button = uibutton(app.UIFigure, 'push');
            app.L3Button.Text = 'L3';
            app.L4Button = uibutton(app.UIFigure, 'push');
            app.L4Button.Text = 'L4';
            app.L5Button = uibutton(app.UIFigure, 'push');
            app.L5Button.Text = 'L5';
            app.L6Button = uibutton(app.UIFigure, 'push');
            app.L6Button.Text = 'L6';
            app.L7Button = uibutton(app.UIFigure, 'push');
            app.L7Button.Text = 'L7';
            app.L8Button = uibutton(app.UIFigure, 'push');
            app.L8Button.Text = 'L8';
            app.L9Button = uibutton(app.UIFigure, 'push');
            app.L9Button.Text = 'L9';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.AmpRawGaugeLabel = uilabel(app.UIFigure);
            app.AmpRawGaugeLabel.Text = 'Amp (Raw)';
            app.AmpRawGauge = uigauge(app.UIFigure, 'linear');
            app.HzSlider = uislider(app.UIFigure);
            app.HzSlider.Limits = [-1 1];
            app.HzSlider_2 = uislider(app.UIFigure);
            app.HzSlider_2.Limits = [-1 1];
            app.HzSlider_3 = uislider(app.UIFigure);
            app.HzSlider_3.Limits = [-1 1];
            app.HzSlider_4 = uislider(app.UIFigure);
            app.HzSlider_4.Limits = [-1 1];
            app.HzSlider_5 = uislider(app.UIFigure);
            app.HzSlider_5.Limits = [-1 1];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '50 / 250 / 600 / 2500/ 5000';
            app.EQLabel = uilabel(app.UIFigure);
            app.EQLabel.Text = 'EQ';
            app.VolumeKnobLabel = uilabel(app.UIFigure);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.UIFigure, 'continuous');
            app.TempoKnobLabel = uilabel(app.UIFigure);
            app.TempoKnobLabel.Text = 'Tempo';
            app.TempoKnob = uiknob(app.UIFigure, 'continuous');
            app.TempoKnob.Limits = [-100 100];
            app.PFactorKnobLabel = uilabel(app.UIFigure);
            app.PFactorKnobLabel.Text = 'P-Factor';
            app.PFactorKnob = uiknob(app.UIFigure, 'continuous');
            app.PFactorKnob.Limits = [-100 100];
            app.DFactorKnobLabel = uilabel(app.UIFigure);
            app.DFactorKnobLabel.Text = 'D-Factor';
            app.DFactorKnob = uiknob(app.UIFigure, 'continuous');
            app.DFactorKnob.Limits = [-100 100];
            app.SynthButton = uibutton(app.UIFigure, 'push');
            app.SynthButton.Text = 'Synth';
            app.SineButton = uibutton(app.UIFigure, 'push');
            app.SineButton.ButtonPushedFcn = createCallbackFcn(app, @SineButtonPushed, true);
            app.SineButton.Text = 'Sine';
            app.SawtoothButton = uibutton(app.UIFigure, 'push');
            app.SawtoothButton.ButtonPushedFcn = createCallbackFcn(app, @SawtoothButtonPushed, true);
            app.SawtoothButton.Text = 'Sawtooth';
            app.SquareButton = uibutton(app.UIFigure, 'push');
            app.SquareButton.ButtonPushedFcn = createCallbackFcn(app, @SquareButtonPushed, true);
            app.SquareButton.Text = 'Square';
            app.BeginsEditFieldLabel = uilabel(app.UIFigure);
            app.BeginsEditFieldLabel.Text = 'Begin (s)';
            app.BeginsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndsEditFieldLabel = uilabel(app.UIFigure);
            app.EndsEditFieldLabel.Text = 'End (s)';
            app.EndsEditField = uieditfield(app.UIFigure, 'numeric');
            app.CropAudioButton = uibutton(app.UIFigure, 'push');
            app.CropAudioButton.ButtonPushedFcn = createCallbackFcn(app, @CropAudioButtonPushed, true);
            app.CropAudioButton.Text = 'Crop Audio';
            app.SampleRateadjHzEditFieldLabel = uilabel(app.UIFigure);
            app.SampleRateadjHzEditFieldLabel.Text = 'Sample Rate adj (Hz)';
            app.SampleRateadjHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdjustSampleRateButton = uibutton(app.UIFigure, 'push');
            app.AdjustSampleRateButton.Text = 'Adjust Sample Rate';
            app.ENG6AudioSamplerLabel = uilabel(app.UIFigure);
            app.ENG6AudioSamplerLabel.Text = 'ENG 6 Audio Sampler';
            app.ByZhuoxuanShiJustinCanasNghiaNguyenLabel = uilabel(app.UIFigure);
            app.ByZhuoxuanShiJustinCanasNghiaNguyenLabel.Text = 'By: Zhuoxuan Shi / Justin Canas / Nghia Nguyen';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audio_samp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end